classdef data_processing_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        image_pnl                matlab.ui.container.Panel
        XLabel_2                 matlab.ui.control.Label
        img_x                    matlab.ui.control.NumericEditField
        YLabel_2                 matlab.ui.control.Label
        img_y                    matlab.ui.control.NumericEditField
        WLabel_2                 matlab.ui.control.Label
        img_w                    matlab.ui.control.NumericEditField
        HLabel_2                 matlab.ui.control.Label
        img_h                    matlab.ui.control.NumericEditField
        RotationCCWLabel         matlab.ui.control.Label
        img_r                    matlab.ui.control.NumericEditField
        ImageFileEditFieldLabel  matlab.ui.control.Label
        image_file_tb            matlab.ui.control.EditField
        ImageFileButton          matlab.ui.control.Button
        NewWLabel                matlab.ui.control.Label
        img_w2                   matlab.ui.control.NumericEditField
        NewHLabel                matlab.ui.control.Label
        img_h2                   matlab.ui.control.NumericEditField
        image_ax                 matlab.ui.control.UIAxes
        lidar_pnl                matlab.ui.container.Panel
        ld_offset_tbl            matlab.ui.control.Table
        XLabel                   matlab.ui.control.Label
        ld_x                     matlab.ui.control.NumericEditField
        YLabel                   matlab.ui.control.Label
        ld_y                     matlab.ui.control.NumericEditField
        WLabel                   matlab.ui.control.Label
        ld_w                     matlab.ui.control.NumericEditField
        HLabel                   matlab.ui.control.Label
        ld_h                     matlab.ui.control.NumericEditField
        tl_x                     matlab.ui.control.Spinner
        TopLabel                 matlab.ui.control.Label
        tl_y                     matlab.ui.control.Spinner
        tr_x                     matlab.ui.control.Spinner
        tr_y                     matlab.ui.control.Spinner
        bl_x                     matlab.ui.control.Spinner
        BottomLabel              matlab.ui.control.Label
        bl_y                     matlab.ui.control.Spinner
        br_x                     matlab.ui.control.Spinner
        br_y                     matlab.ui.control.Spinner
        LidarFileLabel           matlab.ui.control.Label
        lidar_file_tb            matlab.ui.control.EditField
        ClearPointsButton        matlab.ui.control.Button
        LoadLidarStructButton    matlab.ui.control.Button
        xLabel                   matlab.ui.control.Label
        yLabel_2                 matlab.ui.control.Label
        xLabel_3                 matlab.ui.control.Label
        yLabel                   matlab.ui.control.Label
        lidar_ax                 matlab.ui.control.UIAxes
        combined_pnl             matlab.ui.container.Panel
        LoadParamsButton         matlab.ui.control.Button
        SaveParamsButton         matlab.ui.control.Button
        comb_ax                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ld_data             % object to store lidar data
        img_data            % object to store image data
        lidar_struct           % object to store lidar structure data
        save_path           % path to save data
        data_path = fileparts(mfilename('fullpath'));
        start_path = 'D:\IUPUI\Test_Data\';   % Description
        scenario_name       % Description
        camera_side         % Description
        img_cr              % Cropped version of the input image
        ld_cr               % Description
        % test_bs2 comment
    end

    %--------------------------------------------------------------------------
    %--------------------------------------------------------------------------
    %--------------------------------------------------------------------------

    methods (Access = private)
    
    %--------------------------------------------------------------------------

        function plot_lidar(app)
            
            if(isempty(app.ld_data))
                return;
            end
            
            ld_offset = app.ld_offset_tbl.Data;
            %ld_offset = zeros(40,1);
            
            ld_crop_w = [app.ld_x.Value:app.ld_x.Value + app.ld_w.Value - 1];
            ld_crop_h = [app.ld_y.Value:app.ld_y.Value + app.ld_h.Value - 1];
            
            [lx_height, lx_width] = size(app.ld_data);
            
            mp = [1,1; lx_width,1; 1, lx_height; lx_width, lx_height];
            
            fp = [mp(1,1)+app.tl_x.Value, mp(1,2)+app.tl_y.Value; mp(2,1)+app.tr_x.Value, mp(2,2)+app.tr_y.Value; ...
                mp(3,1)+app.bl_x.Value, mp(3,2)+app.bl_y.Value; mp(4,1)+app.br_x.Value, mp(4,2)+app.br_y.Value];
            
            tform = fitgeotrans(mp,fp,'projective');
            RA = imref2d([lx_height lx_width], [1 lx_width], [1 lx_height]);
            
            [lx_w, ref] = imwarp(app.ld_data, tform, 'OutputView', RA);
            %lx_w = imwarp(lx(ld_crop_h, ld_crop_w), tform, 'OutputView', RA);
            
            if(lx_w == 0)
                app.ld_cr = app.ld_data(ld_crop_h, ld_crop_w);
            else  
                for r=1:app.ld_h.Value
%                    tmp_lx(r,:) = lx_w(r+app.ld_y.Value-1, ...
%                        [(app.ld_x.Value-ld_offset(r)):app.ld_x.Value, 1:(size(lx_w,2)-ld_offset(r)-1)]);
%                    
                       if(ld_offset(r)>0)
                           tmp_lx(r,:) = lx_w(r + app.ld_y.Value - 1, ...
                               [lx_width-ld_offset(r):lx_width, 1:(lx_width-ld_offset(r)-1)]);
                       elseif(ld_offset(r) < 0)
                           tmp_lx(r,:) = lx_w(r + app.ld_y.Value - 1, ...
                               [abs(ld_offset(r)):lx_width, 1:abs(ld_offset(r))-1]);
                       else
                           tmp_lx(r,:) = lx_w(r+app.ld_y.Value-1,:);
                       end
                end    
                app.ld_cr = tmp_lx(:, ld_crop_w);
            end
            
%             figure(2)
%             image(lx_w);
%             %imshow(lx_w./max_data,ref);
%             colormap(jet(max_data));
%             axis off
            app.ld_cr = medfilt2(app.ld_cr, [7 1], 'symmetric');
            app.ld_cr = medfilt2(app.ld_cr, [1 7], 'symmetric');  
            
            max_data = (floor(max(app.ld_cr(:))/1000) + 1)*1000;
            
            image(app.lidar_ax, app.ld_cr);
            colormap(app.lidar_ax, jet(max_data));
         
        end
 
    %--------------------------------------------------------------------------
 
        function plot_img(app)
            
            if(isempty(app.img_data))
                return;
            end
            
            crop_w = [app.img_x.Value:app.img_x.Value + app.img_w.Value - 1];
            crop_h = [app.img_y.Value:app.img_y.Value + app.img_h.Value - 1];
            
            if(app.img_r.Value ~= 0.0)
                img_rot = imrotate(app.img_data, app.img_r.Value, 'bilinear');
                app.img_cr = img_rot(crop_h, crop_w, :);
            else
                app.img_cr = app.img_data(crop_h, crop_w, :);
            end
            
            image(app.image_ax, app.img_cr);

        end
    
    %--------------------------------------------------------------------------

        function plot_overlay(app)
            
            if(isempty(app.img_cr) || isempty(app.ld_data) || isempty(app.lidar_struct))
                return;
            end
            f1 = [3 3];
            f3 = [13 1];
            f4 = [1 13];

            if((app.img_h2.Value == app.img_h.Value) && (app.img_w2.Value == app.img_w.Value))
                img_g = rgb2gray(app.img_cr);
            else
                img_g = rgb2gray(imresize(app.img_cr, [app.img_h2.Value, app.img_w2.Value], 'lanczos3'));
            end
            
            %ld_up = imresize(app.ld_cr, size(img_g), 'lanczos3');
            ld_m_up = imresize(app.ld_cr, 2, 'nearest', 'Antialiasing', false);  
            ld_m_up2 = medfilt2(ld_m_up, f3, 'symmetric');
            ld_m_up2 = medfilt2(ld_m_up2, f4, 'symmetric');
            ld_up = imresize(ld_m_up2, size(img_g), 'nearest', 'Antialiasing', false);
            
            ld_up = floor((ld_up - min(ld_up(:)))*255/(max(ld_up(:)) - min(ld_up(:))));
            
            ld_m = medfilt2(ld_up, f1, 'symmetric');
            
            image(app.comb_ax, cat(3, img_g, img_g, ld_m));

        end
                
    %--------------------------------------------------------------------------

        function lx = load_lidar_data(app, lidar_file)
            
            if(isempty(app.lidar_struct))
                file_filter = {'*.mat','MAT Files';'*.*','All Files'};
                ld_struct_path = 'D:\Common\matlab\lidar';
                
                [ld_file, ld_struct_path] = uigetfile(file_filter, 'Select LIDAR data file', ld_struct_path);
                if(ld_struct_path == 0)
                    return;
                end
                        
                S=load(fullfile(ld_struct_path,ld_file));
                app.lidar_struct = S.lidar_struct;
            end
                        
            tmp_data = read_binary_lidar_data(fullfile(app.data_path, app.scenario_name,'lidar', lidar_file));
            [lx, ~, ~] = convert_lidar_to_xyz(app.lidar_struct, tmp_data);
            
            app.lidar_file_tb.Value = strcat(app.scenario_name,'/lidar/',lidar_file);
                       
        end
        
    %--------------------------------------------------------------------------

        function uifigurepop(app)
            drawnow;
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end
        
    end

    %--------------------------------------------------------------------------

    methods (Access = public)
    
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.image_ax, 'off');
            axis(app.lidar_ax, 'off');
            axis(app.comb_ax, 'off');
            app.ld_offset_tbl.Data = zeros(40,1);
        end

        % Button pushed function: ClearPointsButton
        function ClearPointsButtonPushed(app, event)
            app.tl_x.Value = 0;
            app.tl_y.Value = 0;
            app.tr_x.Value = 0;
            app.tr_y.Value = 0;
            app.bl_x.Value = 0;
            app.bl_y.Value = 0;
            app.br_x.Value = 0;
            app.br_y.Value = 0;
            
            update_plots(app, event);

        end

        % Button pushed function: LoadLidarStructButton
        function LoadLidarStructButtonPushed(app, event)
            file_filter = {'*.mat';'*.*'};
            ld_struct_path = 'D:\Common\matlab\lidar';
            
            [ld_file, ld_struct_path] = uigetfile(file_filter, 'Select LIDAR data file', ld_struct_path);
            if(ld_struct_path == 0)
                return;
            end
            
            uifigurepop(app);
            
            S=load(fullfile(ld_struct_path,ld_file));
            app.lidar_struct = S.lidar_struct;            
        end

        % Button pushed function: LoadParamsButton
        function LoadParamsButtonPushed(app, event)
            file_filter = {'*.txt','Image Files';'*.*','All Files' };

            [param_file, param_path] = uigetfile(file_filter, 'Select Parameter File', app.start_path);
            if(param_path == 0)
                ClearPointsButtonPushed(app, event)
                return;
            end
            
            uifigurepop(app);
            
            [params] = parse_input_parameters(fullfile(param_path, param_file));
            
            app.img_x.Value = str2double(params{1}{1});
            app.img_y.Value = str2double(params{1}{2});
            app.img_w.Value = str2double(params{1}{3});
            app.img_h.Value = str2double(params{1}{4});
            app.img_r.Value = str2double(params{1}{5});
            
            app.ld_x.Value = str2double(params{2}{1});
            app.ld_y.Value = str2double(params{2}{2});
            app.ld_w.Value = str2double(params{2}{3});
            app.ld_h.Value = str2double(params{2}{4});
            
            app.tl_x.Value = str2double(params{3}{1});
            app.tl_y.Value = str2double(params{3}{2});
            app.tr_x.Value = str2double(params{3}{3});
            app.tr_y.Value = str2double(params{3}{4});
            app.bl_x.Value = str2double(params{3}{5});
            app.bl_y.Value = str2double(params{3}{6});
            app.br_x.Value = str2double(params{3}{7});
            app.br_y.Value = str2double(params{3}{8});
            
            if(numel(params)<4)
                ld_offset = zeros(40,1);                
            else
                ld_offset = zeros(numel(params{4}),1);
                for idx=1:numel(params{4})
                     ld_offset(idx,1) = str2double(params{4}{idx});
                end
            end
            app.ld_offset_tbl.Data = ld_offset;
            
            update_plots(app, event);

        end

        % Button pushed function: ImageFileButton
        function ImageFileButtonPushed(app, event)
            file_filter = {'*.txt','Image Files';'*.*','All Files' };

            [log_file, log_path] = uigetfile(file_filter, 'Select Focus Listing File', app.start_path);
            if(log_path == 0)
                return;
            end
            app.start_path = log_path;
            
            uifigurepop(app);
            
            [img_files] = parse_input_parameters(fullfile(log_path, log_file));
            app.data_path = img_files{1}{1};
            img_files(1) = [];
            
            app.img_data = imread(fullfile(app.data_path,img_files{max(1,end-2)}{1}));
            app.image_file_tb.Value = img_files{max(1,end-2)}{1};
            
            
            %get the lidar listing
            folders = strsplit(img_files{1}{1},'/');
            app.scenario_name = folders{1};
            app.camera_side = folders{2};
            
            listing = dir(strcat(app.data_path, app.scenario_name, '/lidar/lidar_rng_00000*'));
            lidar_file = listing.name;
            lx = load_lidar_data(app, lidar_file);
            app.ld_data = lx(:,850:1150);
            
            update_plots(app, event);
        end

        % Callback function: bl_x, bl_y, br_x, br_y, img_h, img_h2, 
        % img_w, img_w2, img_x, img_y, ld_h, ld_offset_tbl, ld_w, 
        % ld_x, ld_y, tl_x, tl_y, tr_x, tr_y
        function update_plots(app, event)
            
            plot_img(app);
            plot_lidar(app);
            plot_overlay(app);
            
        end

        % Button pushed function: SaveParamsButton
        function SaveParamsButtonPushed(app, event)
            % save the parameters that were used to create the dataset
            save_location = fullfile(app.data_path,'configs');
            save_name = strcat('params_', app.scenario_name,'_',app.camera_side, '.txt');
                
            file_id = fopen(fullfile(save_location, save_name), 'w');
            fprintf(file_id, '# Parsing parameters: %s - %s\n', app.scenario_name, app.camera_side);
            fprintf(file_id, '# Image Parameters\n');
            fprintf(file_id, '%d, %d, %d, %d, %2.4f\n', app.img_x.Value, app.img_y.Value, app.img_w.Value, app.img_h.Value, app.img_r.Value);
            fprintf(file_id, '# Lidar Parameters\n');
            fprintf(file_id, '%d, %d, %d, %d\n', app.ld_x.Value, app.ld_y.Value, app.ld_w.Value, app.ld_h.Value);
            fprintf(file_id, '# tl_x, tl_y, tr_x, tr_y, bl_x, bl_y, br_x, br_y\n');
            fprintf(file_id, '%d, %d, %d, %d, %d, %d, %d, %d\n', app.tl_x.Value, app.tl_y.Value, app.tr_x.Value, app.tr_y.Value, app.bl_x.Value, app.bl_y.Value, app.br_x.Value, app.br_y.Value);
            fprintf(file_id, '# x pixel offset adjustment\n');
            for idx=1:size(app.ld_offset_tbl.Data,1)-1
                fprintf(file_id, '%d,', app.ld_offset_tbl.Data(idx));
            end
            fprintf(file_id, '%d\n', app.ld_offset_tbl.Data(end));
            fclose(file_id);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.image_pnl = uipanel(app.UIFigure);
            app.image_pnl.Title = 'Input Image';
            app.XLabel_2 = uilabel(app.image_pnl);
            app.XLabel_2.Text = 'X';
            app.img_x = uieditfield(app.image_pnl, 'numeric');
            app.img_x.Limits = [0 Inf];
            app.img_x.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.img_x.Value = 20;
            app.YLabel_2 = uilabel(app.image_pnl);
            app.YLabel_2.Text = 'Y';
            app.img_y = uieditfield(app.image_pnl, 'numeric');
            app.img_y.Limits = [0 Inf];
            app.img_y.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.img_y.Value = 20;
            app.WLabel_2 = uilabel(app.image_pnl);
            app.WLabel_2.Text = 'W';
            app.img_w = uieditfield(app.image_pnl, 'numeric');
            app.img_w.Limits = [0 Inf];
            app.img_w.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.img_w.Value = 900;
            app.HLabel_2 = uilabel(app.image_pnl);
            app.HLabel_2.Text = 'H';
            app.img_h = uieditfield(app.image_pnl, 'numeric');
            app.img_h.Limits = [0 Inf];
            app.img_h.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.img_h.Value = 720;
            app.RotationCCWLabel = uilabel(app.image_pnl);
            app.RotationCCWLabel.Text = 'Rotation (CCW)';
            app.img_r = uieditfield(app.image_pnl, 'numeric');
            app.img_r.Limits = [0 Inf];
            app.ImageFileEditFieldLabel = uilabel(app.image_pnl);
            app.ImageFileEditFieldLabel.Text = 'Image File';
            app.image_file_tb = uieditfield(app.image_pnl, 'text');
            app.ImageFileButton = uibutton(app.image_pnl, 'push');
            app.ImageFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImageFileButtonPushed, true);
            app.ImageFileButton.Text = 'Image File';
            app.NewWLabel = uilabel(app.image_pnl);
            app.NewWLabel.Text = 'New W';
            app.img_w2 = uieditfield(app.image_pnl, 'numeric');
            app.img_w2.Limits = [0 Inf];
            app.img_w2.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.img_w2.Value = 900;
            app.NewHLabel = uilabel(app.image_pnl);
            app.NewHLabel.Text = 'New H';
            app.img_h2 = uieditfield(app.image_pnl, 'numeric');
            app.img_h2.Limits = [0 Inf];
            app.img_h2.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.img_h2.Value = 720;
            app.image_ax = uiaxes(app.image_pnl);
            app.lidar_pnl = uipanel(app.UIFigure);
            app.lidar_pnl.Title = 'Lidar Image';
            app.ld_offset_tbl = uitable(app.lidar_pnl);
            app.ld_offset_tbl.ColumnName = {'Offset'};
            app.ld_offset_tbl.RowName = {};
            app.ld_offset_tbl.ColumnEditable = true;
            app.ld_offset_tbl.CellEditCallback = createCallbackFcn(app, @update_plots, true);
            app.XLabel = uilabel(app.lidar_pnl);
            app.XLabel.Text = 'X';
            app.ld_x = uieditfield(app.lidar_pnl, 'numeric');
            app.ld_x.Limits = [0 Inf];
            app.ld_x.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.ld_x.Value = 10;
            app.YLabel = uilabel(app.lidar_pnl);
            app.YLabel.Text = 'Y';
            app.ld_y = uieditfield(app.lidar_pnl, 'numeric');
            app.ld_y.Limits = [0 Inf];
            app.ld_y.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.ld_y.Value = 10;
            app.WLabel = uilabel(app.lidar_pnl);
            app.WLabel.Text = 'W';
            app.ld_w = uieditfield(app.lidar_pnl, 'numeric');
            app.ld_w.Limits = [0 Inf];
            app.ld_w.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.ld_w.Value = 150;
            app.HLabel = uilabel(app.lidar_pnl);
            app.HLabel.Text = 'H';
            app.ld_h = uieditfield(app.lidar_pnl, 'numeric');
            app.ld_h.Limits = [0 Inf];
            app.ld_h.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.ld_h.Value = 40;
            app.tl_x = uispinner(app.lidar_pnl);
            app.tl_x.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.TopLabel = uilabel(app.lidar_pnl);
            app.TopLabel.Text = 'Top';
            app.tl_y = uispinner(app.lidar_pnl);
            app.tl_y.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.tr_x = uispinner(app.lidar_pnl);
            app.tr_x.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.tr_y = uispinner(app.lidar_pnl);
            app.tr_y.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.bl_x = uispinner(app.lidar_pnl);
            app.bl_x.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.BottomLabel = uilabel(app.lidar_pnl);
            app.BottomLabel.Text = 'Bottom';
            app.bl_y = uispinner(app.lidar_pnl);
            app.bl_y.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.br_x = uispinner(app.lidar_pnl);
            app.br_x.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.br_y = uispinner(app.lidar_pnl);
            app.br_y.ValueChangedFcn = createCallbackFcn(app, @update_plots, true);
            app.LidarFileLabel = uilabel(app.lidar_pnl);
            app.LidarFileLabel.Text = 'Lidar File';
            app.lidar_file_tb = uieditfield(app.lidar_pnl, 'text');
            app.ClearPointsButton = uibutton(app.lidar_pnl, 'push');
            app.ClearPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPointsButtonPushed, true);
            app.ClearPointsButton.Text = 'Clear Points';
            app.LoadLidarStructButton = uibutton(app.lidar_pnl, 'push');
            app.LoadLidarStructButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLidarStructButtonPushed, true);
            app.LoadLidarStructButton.Text = 'Load Lidar Struct';
            app.xLabel = uilabel(app.lidar_pnl);
            app.xLabel.Text = 'x';
            app.yLabel_2 = uilabel(app.lidar_pnl);
            app.yLabel_2.Text = 'y';
            app.xLabel_3 = uilabel(app.lidar_pnl);
            app.xLabel_3.Text = 'x';
            app.yLabel = uilabel(app.lidar_pnl);
            app.yLabel.Text = 'y';
            app.lidar_ax = uiaxes(app.lidar_pnl);
            app.combined_pnl = uipanel(app.UIFigure);
            app.combined_pnl.Title = 'Combined Overlay';
            app.LoadParamsButton = uibutton(app.combined_pnl, 'push');
            app.LoadParamsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParamsButtonPushed, true);
            app.LoadParamsButton.Text = 'Load Params';
            app.SaveParamsButton = uibutton(app.combined_pnl, 'push');
            app.SaveParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParamsButtonPushed, true);
            app.SaveParamsButton.Text = 'Save Params';
            app.comb_ax = uiaxes(app.combined_pnl);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_processing_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end